classdef arduinoUserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ArduinoUIUIFigure  matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        GPIOPanel          matlab.ui.container.Panel
        Gauge              matlab.ui.control.SemicircularGauge
        Gauge2             matlab.ui.control.SemicircularGauge
        Gauge3             matlab.ui.control.SemicircularGauge
        Gauge4             matlab.ui.control.SemicircularGauge
        Gauge5             matlab.ui.control.SemicircularGauge
        Gauge6             matlab.ui.control.SemicircularGauge
        D3Label            matlab.ui.control.Label
        D3EditField        matlab.ui.control.NumericEditField
        D5EditFieldLabel   matlab.ui.control.Label
        D5EditField        matlab.ui.control.NumericEditField
        D6EditFieldLabel   matlab.ui.control.Label
        D6EditField        matlab.ui.control.NumericEditField
        D9EditFieldLabel   matlab.ui.control.Label
        D9EditField        matlab.ui.control.NumericEditField
        D10EditFieldLabel  matlab.ui.control.Label
        D10EditField       matlab.ui.control.NumericEditField
        D11EditFieldLabel  matlab.ui.control.Label
        D11EditField       matlab.ui.control.NumericEditField
        DI2Lamp            matlab.ui.control.Lamp
        DI4Lamp            matlab.ui.control.Lamp
        DI7Lamp            matlab.ui.control.Lamp
        DI8Lamp            matlab.ui.control.Lamp
        DI12Lamp           matlab.ui.control.Lamp
        DI13Lamp           matlab.ui.control.Lamp
        DI2Label           matlab.ui.control.Label
        DI4Label           matlab.ui.control.Label
        DI7Label           matlab.ui.control.Label
        DI8Label           matlab.ui.control.Label
        DI12Label          matlab.ui.control.Label
        DI13Label          matlab.ui.control.Label
        SETButton          matlab.ui.control.Button
        SETButton_2        matlab.ui.control.Button
        SETButton_3        matlab.ui.control.Button
        SETButton_4        matlab.ui.control.Button
        SETButton_5        matlab.ui.control.Button
        SETButton_6        matlab.ui.control.Button
        RESETButton        matlab.ui.control.Button
        RESETButton_2      matlab.ui.control.Button
        RESETButton_3      matlab.ui.control.Button
        RESETButton_4      matlab.ui.control.Button
        RESETButton_5      matlab.ui.control.Button
        RESETButton_6      matlab.ui.control.Button
    end

    
    properties (Access = private)
        ard;
        t;
        status = "disconnected";
        a = 0;
        DI2 = 0;
        DI3 = 0;
        DI4 = 0;
        DI5 = 0;
        DI6 = 0;
        DI7 = 0;
        DI8 = 0;
        DI9 = 0;
        DI10 = 0;
        DI11 = 0;
        DI12 = 0;
        DI13 = 0;
        AI0;
        AI1;
        AI2;
        AI3;
        AI4;
        AI5;
        counter = 1;
        plotAi0 = double.empty(100, 0);
        plotAi1 = double.empty(100, 0);
        plotAi2 = double.empty(100, 0);
        plotAi3 = double.empty(100, 0);
        plotAi4 = double.empty(100, 0);
        plotAi5 = double.empty(100, 0);
    end
    
    
    methods (Access = private)
        function readData(app, ~, ~)
            if app.status == "disconnected" 
                app.ard = arduino;
                configurePin(app.ard, 'D2', 'DigitalOutput');
                configurePin(app.ard, 'D4', 'DigitalOutput');
                configurePin(app.ard, 'D7', 'DigitalOutput');
                configurePin(app.ard, 'D8', 'DigitalOutput');
                configurePin(app.ard, 'D12', 'DigitalOutput');
                configurePin(app.ard, 'D13', 'DigitalOutput');
                app.status = "connected";
            else          
                app.AI0 = readVoltage(app.ard, 'A0');
                app.AI1 = readVoltage(app.ard, 'A1');
                app.AI2 = readVoltage(app.ard, 'A2');
                app.AI3 = readVoltage(app.ard, 'A3');
                app.AI4 = readVoltage(app.ard, 'A4');
                app.AI5 = readVoltage(app.ard, 'A5');
                writeDigitalPin(app.ard, 'D2', app.DI2);
                writeDigitalPin(app.ard, 'D4', app.DI4);
                writeDigitalPin(app.ard, 'D7', app.DI7);
                writeDigitalPin(app.ard, 'D8', app.DI8);
                writeDigitalPin(app.ard, 'D12', app.DI12);
                writeDigitalPin(app.ard, 'D13', app.DI13);
                writePWMVoltage(app.ard, 'D3', app.DI3);
                writePWMVoltage(app.ard, 'D5', app.DI5);
                writePWMVoltage(app.ard, 'D6', app.DI6);
                writePWMVoltage(app.ard, 'D9', app.DI9);
                writePWMVoltage(app.ard, 'D10', app.DI10);
                writePWMVoltage(app.ard, 'D11', app.DI11);
                app.graphics();
            end
        end
        
        function graphics(app)
            app.plotAi0(app.counter) = app.AI0;
            app.plotAi1(app.counter) = app.AI1;   
            app.plotAi2(app.counter) = app.AI2;
            app.plotAi3(app.counter) = app.AI3; 
            app.plotAi4(app.counter) = app.AI4;
            app.plotAi5(app.counter) = app.AI5;
            app.counter = app.counter + 1;
            plot(app.UIAxes, app.plotAi0);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.plotAi1);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.plotAi2);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.plotAi3);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.plotAi4);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.plotAi5);
            hold(app.UIAxes, 'off');            
            legend(app.UIAxes, {'AI0','AI1', 'AI2', 'AI3', 'AI4', 'AI5'},...
                'Location','southeast','Orientation','horizontal');
            if app.counter > 99
                app.counter = 1;
                clear app.plotAi0;
                clear app.plotAi1;
                clear app.plotAi2;
                clear app.plotAi3;
                clear app.plotAi4;
                clear app.plotAi5;
                app.plotAi0 = double.empty(100, 0);
                app.plotAi1 = double.empty(100, 0);
                app.plotAi2 = double.empty(100, 0);
                app.plotAi3 = double.empty(100, 0);
                app.plotAi4 = double.empty(100, 0);
                app.plotAi5 = double.empty(100, 0);
                plot(app.UIAxes, 0, 0);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.t = timer;
           app.t.period = 0.05;
           app.t.TimerFcn = @app.readData;
           app.t.ExecutionMode = 'fixedRate';
           start(app.t);
        end

        % Close request function: ArduinoUIUIFigure
        function ArduinoUIUIFigureCloseRequest(app, event)
            stop(app.t)
            clear app.ard
            delete(app)
        end

        % Button pushed function: SETButton
        function setDi2(app, event)
            app.DI2 = 1;
            app.DI2Lamp.Color = 'green';
        end

        % Button pushed function: SETButton_2
        function setDi4(app, event)
            app.DI4 = 1;
            app.DI4Lamp.Color = 'green';
        end

        % Button pushed function: SETButton_3
        function setDi7(app, event)
            app.DI7 = 1;
            app.DI7Lamp.Color = 'green';
        end

        % Button pushed function: SETButton_4
        function setDi8(app, event)
            app.DI8 = 1;
            app.DI8Lamp.Color = 'green';
        end

        % Button pushed function: SETButton_5
        function setDi12(app, event)
            app.DI12 = 1;
            app.DI12Lamp.Color = 'green';
        end

        % Button pushed function: SETButton_6
        function setDi13(app, event)
            app.DI13 = 1;
            app.DI13Lamp.Color = 'green';
        end

        % Button pushed function: RESETButton
        function resetDi2(app, event)
            app.DI2 = 0;
            app.DI2Lamp.Color = 'red';
        end

        % Button pushed function: RESETButton_2
        function resetDi4(app, event)
            app.DI4 = 0;
            app.DI4Lamp.Color = 'red';
        end

        % Button pushed function: RESETButton_3
        function resetDi7(app, event)
            app.DI7 = 0;
            app.DI7Lamp.Color = 'red';
        end

        % Button pushed function: RESETButton_4
        function resetDi8(app, event)
            app.DI8 = 0;
            app.DI8Lamp.Color = 'red';
        end

        % Button pushed function: RESETButton_5
        function resetDi12(app, event)
            app.DI12 = 0;
            app.DI12Lamp.Color = 'red';
        end

        % Button pushed function: RESETButton_6
        function resetDi13(app, event)
            app.DI13 = 0;
            app.DI13Lamp.Color = 'red';
        end

        % Value changed function: D3EditField
        function pwm3(app, event)
            value = app.D3EditField.Value;
            if value >= 0 && value <= 5
                app.DI3 = value;
                app.Gauge.Value = value;
            else
                msgbox({'Invalid Value';'Please enter a number between 0 and 5'},'Error','error');
                app.D3EditField.Value = 0;
                app.Gauge.Value = 0;
            end
        end

        % Value changed function: D5EditField
        function pwm5(app, event)
            value = app.D5EditField.Value;
            if value >= 0 && value <= 5
                app.DI5 = value;
                app.Gauge2.Value = value;
            else
                msgbox({'Invalid Value';'Please enter a number between 0 and 5'},'Error','error');
                app.D5EditField.Value = 0;
                app.Gauge2.Value = 0;
            end
        end

        % Value changed function: D6EditField
        function pwm6(app, event)
            value = app.D6EditField.Value;
            if value >= 0 && value <= 5
                app.DI6 = value;
                app.Gauge3.Value = value;
            else
                msgbox({'Invalid Value';'Please enter a number between 0 and 5'},'Error','error');
                app.D6EditField.Value = 0;
                app.Gauge3.Value = 0;
            end
        end

        % Value changed function: D9EditField
        function pwm9(app, event)
            value = app.D9EditField.Value;
            if value >= 0 && value <= 5
                app.DI9 = value;
                app.Gauge4.Value = value;
            else
                msgbox({'Invalid Value';'Please enter a number between 0 and 5'},'Error','error');
                app.D9EditField.Value = 0;
                app.Gauge4.Value = 0;
            end
        end

        % Value changed function: D10EditField
        function pwm10(app, event)
            value = app.D10EditField.Value;
            if value >= 0 && value <= 5
                app.DI10 = value;
                app.Gauge5.Value = value;
            else
                msgbox({'Invalid Value';'Please enter a number between 0 and 5'},'Error','error');
                app.D10EditField.Value = 0;
                app.Gauge5.Value = 0;
            end
        end

        % Value changed function: D11EditField
        function pwm11(app, event)
            value = app.D11EditField.Value;
            if value >= 0 && value <= 5
                app.DI11 = value;
                app.Gauge6.Value = value;
            else
                msgbox({'Invalid Value';'Please enter a number between 0 and 5'},'Error','error');
                app.D11EditField.Value = 0;
                app.Gauge6.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ArduinoUIUIFigure = uifigure('Visible', 'off');
            app.ArduinoUIUIFigure.Name = 'Arduino UI';
            app.ArduinoUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ArduinoUIUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ArduinoUIUIFigure);
            title(app.UIAxes, 'Analog inputs')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YLim = [0 5];
            app.GPIOPanel = uipanel(app.ArduinoUIUIFigure);
            app.GPIOPanel.Title = 'GPIO';
            app.Gauge = uigauge(app.GPIOPanel, 'semicircular');
            app.Gauge.Limits = [0 5];
            app.Gauge2 = uigauge(app.GPIOPanel, 'semicircular');
            app.Gauge2.Limits = [0 5];
            app.Gauge3 = uigauge(app.GPIOPanel, 'semicircular');
            app.Gauge3.Limits = [0 5];
            app.Gauge4 = uigauge(app.GPIOPanel, 'semicircular');
            app.Gauge4.Limits = [0 5];
            app.Gauge5 = uigauge(app.GPIOPanel, 'semicircular');
            app.Gauge5.Limits = [0 5];
            app.Gauge6 = uigauge(app.GPIOPanel, 'semicircular');
            app.Gauge6.Limits = [0 5];
            app.D3Label = uilabel(app.GPIOPanel);
            app.D3Label.Text = {'D3'; ''};
            app.D3EditField = uieditfield(app.GPIOPanel, 'numeric');
            app.D3EditField.ValueChangedFcn = createCallbackFcn(app, @pwm3, true);
            app.D5EditFieldLabel = uilabel(app.GPIOPanel);
            app.D5EditFieldLabel.Text = 'D5';
            app.D5EditField = uieditfield(app.GPIOPanel, 'numeric');
            app.D5EditField.ValueChangedFcn = createCallbackFcn(app, @pwm5, true);
            app.D6EditFieldLabel = uilabel(app.GPIOPanel);
            app.D6EditFieldLabel.Text = 'D6';
            app.D6EditField = uieditfield(app.GPIOPanel, 'numeric');
            app.D6EditField.ValueChangedFcn = createCallbackFcn(app, @pwm6, true);
            app.D9EditFieldLabel = uilabel(app.GPIOPanel);
            app.D9EditFieldLabel.Text = 'D9';
            app.D9EditField = uieditfield(app.GPIOPanel, 'numeric');
            app.D9EditField.ValueChangedFcn = createCallbackFcn(app, @pwm9, true);
            app.D10EditFieldLabel = uilabel(app.GPIOPanel);
            app.D10EditFieldLabel.Text = 'D10';
            app.D10EditField = uieditfield(app.GPIOPanel, 'numeric');
            app.D10EditField.ValueChangedFcn = createCallbackFcn(app, @pwm10, true);
            app.D11EditFieldLabel = uilabel(app.GPIOPanel);
            app.D11EditFieldLabel.Text = 'D11';
            app.D11EditField = uieditfield(app.GPIOPanel, 'numeric');
            app.D11EditField.ValueChangedFcn = createCallbackFcn(app, @pwm11, true);
            app.DI2Lamp = uilamp(app.GPIOPanel);
            app.DI4Lamp = uilamp(app.GPIOPanel);
            app.DI7Lamp = uilamp(app.GPIOPanel);
            app.DI8Lamp = uilamp(app.GPIOPanel);
            app.DI12Lamp = uilamp(app.GPIOPanel);
            app.DI13Lamp = uilamp(app.GPIOPanel);
            app.DI2Label = uilabel(app.GPIOPanel);
            app.DI2Label.Text = 'DI2';
            app.DI4Label = uilabel(app.GPIOPanel);
            app.DI4Label.Text = 'DI4';
            app.DI7Label = uilabel(app.GPIOPanel);
            app.DI7Label.Text = 'DI7';
            app.DI8Label = uilabel(app.GPIOPanel);
            app.DI8Label.Text = 'DI8';
            app.DI12Label = uilabel(app.GPIOPanel);
            app.DI12Label.Text = 'DI12';
            app.DI13Label = uilabel(app.GPIOPanel);
            app.DI13Label.Text = 'DI13';
            app.SETButton = uibutton(app.GPIOPanel, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @setDi2, true);
            app.SETButton.Text = {'SET'; ''};
            app.SETButton_2 = uibutton(app.GPIOPanel, 'push');
            app.SETButton_2.ButtonPushedFcn = createCallbackFcn(app, @setDi4, true);
            app.SETButton_2.Text = {'SET'; ''};
            app.SETButton_3 = uibutton(app.GPIOPanel, 'push');
            app.SETButton_3.ButtonPushedFcn = createCallbackFcn(app, @setDi7, true);
            app.SETButton_3.Text = {'SET'; ''};
            app.SETButton_4 = uibutton(app.GPIOPanel, 'push');
            app.SETButton_4.ButtonPushedFcn = createCallbackFcn(app, @setDi8, true);
            app.SETButton_4.Text = 'SET';
            app.SETButton_5 = uibutton(app.GPIOPanel, 'push');
            app.SETButton_5.ButtonPushedFcn = createCallbackFcn(app, @setDi12, true);
            app.SETButton_5.Text = 'SET';
            app.SETButton_6 = uibutton(app.GPIOPanel, 'push');
            app.SETButton_6.ButtonPushedFcn = createCallbackFcn(app, @setDi13, true);
            app.SETButton_6.Text = 'SET';
            app.RESETButton = uibutton(app.GPIOPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @resetDi2, true);
            app.RESETButton.Text = 'RESET';
            app.RESETButton_2 = uibutton(app.GPIOPanel, 'push');
            app.RESETButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetDi4, true);
            app.RESETButton_2.Text = 'RESET';
            app.RESETButton_3 = uibutton(app.GPIOPanel, 'push');
            app.RESETButton_3.ButtonPushedFcn = createCallbackFcn(app, @resetDi7, true);
            app.RESETButton_3.Text = 'RESET';
            app.RESETButton_4 = uibutton(app.GPIOPanel, 'push');
            app.RESETButton_4.ButtonPushedFcn = createCallbackFcn(app, @resetDi8, true);
            app.RESETButton_4.Text = 'RESET';
            app.RESETButton_5 = uibutton(app.GPIOPanel, 'push');
            app.RESETButton_5.ButtonPushedFcn = createCallbackFcn(app, @resetDi12, true);
            app.RESETButton_5.Text = 'RESET';
            app.RESETButton_6 = uibutton(app.GPIOPanel, 'push');
            app.RESETButton_6.ButtonPushedFcn = createCallbackFcn(app, @resetDi13, true);
            app.RESETButton_6.Text = 'RESET';
            % Show the figure after all components are created
            app.ArduinoUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoUserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ArduinoUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ArduinoUIUIFigure)
        end
    end
end